classdef ADXL335CalibrationProgram < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        BaudrateEditField             matlab.ui.control.NumericEditField
        BaudrateEditFieldLabel        matlab.ui.control.Label
        ScnBtn                        matlab.ui.control.Button
        CBtn                          matlab.ui.control.Button
        SDDD                          matlab.ui.control.DropDown
        SerialDeviceDropDownLabel     matlab.ui.control.Label
        SCBtnZ                        matlab.ui.control.Button
        SCBtnY                        matlab.ui.control.Button
        SCBtnX                        matlab.ui.control.Button
        ResZ                          matlab.ui.control.Label
        ResY                          matlab.ui.control.Label
        ResX                          matlab.ui.control.Label
        iz                            matlab.ui.container.GridLayout
        SPZ                           matlab.ui.control.NumericEditField
        SampleTimeLabel               matlab.ui.control.Label
        iy                            matlab.ui.container.GridLayout
        SPY                           matlab.ui.control.NumericEditField
        SamplePeriodEditField_2Label  matlab.ui.control.Label
        ix                            matlab.ui.container.GridLayout
        SPX                           matlab.ui.control.NumericEditField
        SamplePeriodEditFieldLabel    matlab.ui.control.Label
        az                            matlab.ui.control.UIAxes
        ay                            matlab.ui.control.UIAxes
        ax                            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Animated lines
        hx
        hy
        hz
        serial_interface
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            app.hx = animatedline(Parent=app.ax);
            app.hy = animatedline(Parent=app.ay);
            app.hz = animatedline(Parent=app.az);
        end

        % Button pushed function: ScnBtn
        function ScnBtnButtonPushed(app, event)
            app.SDDD.Items = serialportlist("available");
        end

        % Button pushed function: CBtn
        function CBtnButtonPushed(app, event)
            app.serial_interface
            if(isempty(app.serial_interface) || ~app.serial_interface.is_reading())
                app.serial_interface = ADXLInterface(app.SDDD.Value,app.BaudrateEditField.Value);
                app.CBtn.Text = "Disconnect";
            else
                app.CBtn.Text = "Connect";
                app.serial_interface.delete()
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ax = uiaxes(app.GridLayout);
            xlabel(app.ax, 't')
            ylabel(app.ax, 'X')
            zlabel(app.ax, 'Z')
            app.ay = uiaxes(app.GridLayout);
            xlabel(app.ay, 't')
            ylabel(app.ay, 'Y')
            zlabel(app.ay, 'Z')
            app.az = uiaxes(app.GridLayout);
            xlabel(app.az, 't')
            ylabel(app.az, 'Z')
            zlabel(app.az, 'Z')
            app.ix = uigridlayout(app.GridLayout);
            app.SamplePeriodEditFieldLabel = uilabel(app.ix);
            app.SamplePeriodEditFieldLabel.Text = 'Sample Period';
            app.SPX = uieditfield(app.ix, 'numeric');
            app.iy = uigridlayout(app.GridLayout);
            app.SamplePeriodEditField_2Label = uilabel(app.iy);
            app.SamplePeriodEditField_2Label.Text = 'Sample Period';
            app.SPY = uieditfield(app.iy, 'numeric');
            app.iz = uigridlayout(app.GridLayout);
            app.SampleTimeLabel = uilabel(app.iz);
            app.SampleTimeLabel.Text = 'Sample Period';
            app.SPZ = uieditfield(app.iz, 'numeric');
            app.ResX = uilabel(app.GridLayout);
            app.ResY = uilabel(app.GridLayout);
            app.ResY.Text = 'Label2';
            app.ResZ = uilabel(app.GridLayout);
            app.ResZ.Text = 'Label3';
            app.SCBtnX = uibutton(app.GridLayout, 'push');
            app.SCBtnX.Text = 'Start Calibration';
            app.SCBtnY = uibutton(app.GridLayout, 'push');
            app.SCBtnY.Text = 'Start Calibration';
            app.SCBtnZ = uibutton(app.GridLayout, 'push');
            app.SCBtnZ.Text = 'Start Calibration';
            app.SerialDeviceDropDownLabel = uilabel(app.GridLayout);
            app.SerialDeviceDropDownLabel.Text = 'Serial Device';
            app.SDDD = uidropdown(app.GridLayout);
            app.SDDD.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6'};
            app.SDDD.Value = 'COM1';
            app.CBtn = uibutton(app.GridLayout, 'push');
            app.CBtn.ButtonPushedFcn = createCallbackFcn(app, @CBtnButtonPushed, true);
            app.CBtn.Text = 'Connect';
            app.ScnBtn = uibutton(app.GridLayout, 'push');
            app.ScnBtn.ButtonPushedFcn = createCallbackFcn(app, @ScnBtnButtonPushed, true);
            app.ScnBtn.Text = 'Scan Serial Device';
            app.BaudrateEditFieldLabel = uilabel(app.GridLayout);
            app.BaudrateEditFieldLabel.Text = 'Baudrate';
            app.BaudrateEditField = uieditfield(app.GridLayout, 'numeric');
            app.BaudrateEditField.ValueDisplayFormat = '%11.100g';
            app.BaudrateEditField.Value = 115200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADXL335CalibrationProgram

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end